classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        Panel_5                 matlab.ui.container.Panel
        TextArea_log            matlab.ui.control.TextArea
        Panel_4                 matlab.ui.container.Panel
        DropDown_matchmethod    matlab.ui.control.DropDown
        Label_6                 matlab.ui.control.Label
        DropDown_pyramid        matlab.ui.control.DropDown
        Label_5                 matlab.ui.control.Label
        DropDown_imagetype      matlab.ui.control.DropDown
        Label_4                 matlab.ui.control.Label
        DropDown_algorithm      matlab.ui.control.DropDown
        Label_3                 matlab.ui.control.Label
        Button_run              matlab.ui.control.Button
        Panel_3                 matlab.ui.container.Panel
        UIAxes_template         matlab.ui.control.UIAxes
        UIAxes_input            matlab.ui.control.UIAxes
        Panel_2                 matlab.ui.container.Panel
        Button_load_two         matlab.ui.control.Button
        Button_browse_two       matlab.ui.control.Button
        Button_load1            matlab.ui.control.Button
        Button_browse1          matlab.ui.control.Button
        EditField_templatepath  matlab.ui.control.EditField
        Label_2                 matlab.ui.control.Label
        EditField_inputpath     matlab.ui.control.EditField
        Label                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        StrArray % Description
        I_ori % 输入图像
        I_com % 模板图像
        row_ori % Description
        column_ori % Description
        row_com % Description
        column_com % Description
        cnt_ori % Description
        cnt_com % Description
        S % 特征邻域窗口大小
        K % 高斯滤波半径
        N % 特征位数
        cnt1 % Description
        cnt2
        imagetype_num % Description
        pyramid_num % Description
        matchmethod_num
        algorithm_num % Description
    end
    
    methods (Access = private)
        
        function logRefresh_func(app, StrArrayNew)
            if length(app.StrArray) >= 20
                app.StrArray = {};
            end
            app.StrArray = [app.StrArray, StrArrayNew];
            app.TextArea_log.Value = app.StrArray;
        end
        
        function shape_feature(app, I_ori, I_com)
            app.S = 48;
            app.K = 5;
            app.N = 256;
            level = 3;                                                      %金字塔层数
            threshold_FAST_bw = 0;                                          %FAST使用的阈值
            threshold_FAST_grey = 30;
            threshold_HARRIS_bw = 0.001;                                    %HARRIS使用的阈值
            threshold_HARRIS_grey = 1000;
            app.cnt_ori = zeros(1, 100);
            app.cnt_com = 0;
            diff = 110;
            id = 0;                                                         %临时的变量
            
            if app.imagetype_num == 1
                threshold_FAST = threshold_FAST_grey;
                threshold_HARRIS = threshold_HARRIS_grey;
            else
                threshold_FAST = threshold_FAST_bw;
                threshold_HARRIS = threshold_HARRIS_bw;
            end
            
            I_ori = rgb2gray(I_ori);                                        %原始图像
            I_com = rgb2gray(I_com);                                        %需要检测的目标图像
            
            [app.row_ori, app.column_ori] = size(I_ori);
            [app.row_com, app.column_com] = size(I_com);                      %图像的尺寸
            
            calc_hist(app, I_ori);
            calc_hist(app, I_com);
%             app.n = max(app.row_ori, app.row_com);
%             app.m = max(app.column_ori, app.column_com);
%             I_ori = imresize(I_ori, [app.n app.m], 'bicubic');
            [I_ori_p, gra_ori] = Initialize(app, I_ori);                           %预处理图像
            [I_com_p, gra_com] = Initialize(app, I_com);
            
%             calc_hist(app, I_ori_p);
%             calc_hist(app, I_com_p);
%             figure
%             imhist(uint8(I_ori));
%             figure
%             imhist(uint8(I_ori_p));
            
            if app.algorithm_num == 0 || app.imagetype_num == 0
                return;
            end
            
            if app.algorithm_num == 3
                shape_context(app, I_ori_p, I_com_p, gra_ori, gra_com);
                return
            end
            
            Pyramid = Construct_Pyramid(app, I_ori_p, level);                %构建图像金字塔，输入图像和金字塔层数
            
%             Points_Pyramid = zeros(2, 200, 10);
            gauss_filter = fspecial('gaussian', [5, 5], 0.5);                 %归一化的高斯滤波器
            tic
            i = 1;
%             for i = 1 : level + 1
                if app.algorithm_num == 1
                    Points_Pyramid(:, :, i) = HARRIS(app, Pyramid(:, :, i), threshold_HARRIS, gauss_filter, i); 
                else
                    Points_Pyramid(:, :, i) = FAST(app, Pyramid(:, :, i), threshold_FAST, i); 
                end
%             end
            if app.algorithm_num == 1
                Points_com = HARRIS(app, I_com_p, threshold_HARRIS, gauss_filter, 0);
            else
                Points_com = FAST(app, I_com_p, threshold_FAST, 0);                   %FAST算法得到特征点，输入金字塔和层数 
            end
            toc
            figure
            if app.imagetype_num == 1
                imshow(uint8(I_ori_p))
            else
                imshow(I_ori_p)
            end
            hold on
            for i = 1 : app.cnt_ori(1)
                x = Points_Pyramid(1, i, 1);
                y = Points_Pyramid(2, i, 1);
                plot(y, x, 'r*')
                hold on
            end
            figure
            if app.imagetype_num == 1
                imshow(uint8(I_com_p))
            else
                imshow(I_com_p)
            end
            hold on
            for i = 1 : app.cnt_com
                x = Points_com(1, i);
                y = Points_com(2, i);
                plot(y, x, 'r*')
                hold on
            end
            
            s = normrnd(0, app.S/5, 4, app.N);
            Descriptor_ori = zeros(100, app.N, 10);
            
            for i = 1 : level + 1
                if(app.cnt_ori(i) == 0)
                    continue;
                end
                Descriptor_ori(1 : app.cnt_ori(i), :, i) = ORB(app, Points_Pyramid(:, :, i), Pyramid(:, :, i), i, s);
            end
            Descriptor_com = ORB(app, Points_com, I_com_p, 0, s);   %计算得到orb描述子
            
%             for i = 1 : level + 1
%                 if (abs(app.cnt_ori(i) - app.cnt_com) < diff)
%                     diff = abs(app.cnt_ori(i) - app.cnt_com);
%                     id = i;
%                 end
%             end
            id = 1;
            if (app.cnt_ori(id) <= app.cnt_com)
                Match(app, Points_Pyramid(:, :, id), Points_com, Pyramid(:, :, id), I_com_p, Descriptor_ori, Descriptor_com, id, 1);
                %toc
            else
                Match(app, Points_com, Points_Pyramid(:, :, i), I_com_p, Pyramid(:, :, id), Descriptor_com, Descriptor_ori, id, 2);  %特征点匹配,1表示前面的串是三维，2表示后面是三维
            end
        end
        
        function p_out = HARRIS(app, I_in, threshold, gauss_filter, Flag)
            graph = double(I_in);
            [n, m] = size(graph);
            windowx = [-1, 0, 1; -1, 0, 1; -1, 0, 1];
            windowy = windowx';
            p_out = zeros(2, 1000);
            cnt = 0;
            localmax = zeros(n, m);

            Ix = imfilter(graph, windowx);
            Iy = imfilter(graph, windowy);
            Ix2 = Ix .* Ix;
            Iy2 = Iy .* Iy;
            Ixy = Ix .* Iy;

            Ix2 = imfilter(Ix2, gauss_filter);
            Iy2 = imfilter(Iy2, gauss_filter);
            Ixy = imfilter(Ixy, gauss_filter);
        
            R = (Ix2 .* Iy2 - Ixy .* Ixy) ./ (Ix2 + Iy2 + 0.00001);
        
            for i = 3 : n - 2
                for j = 3 : m - 2
                    localmax(i, j) = max(max(R(i - 2 : i + 2, j - 2 : j + 2)));
                end
            end
            for i = 3 : n - 2
                 for j = 3 : m - 2
                     if (localmax(i, j) == R(i, j) && R(i, j) > threshold)
                         cnt = cnt + 1;
                         p_out(1, cnt) = i;
                         p_out(2, cnt) = j;
                     end
                 end
            end
            p = p_out;
            p = p(:, p(1, :) > app.S & p(1, :) < n - app.S & p(2, :) > app.S & p(2, :) < m - app.S);
            cnt = length(p(1, :));
            p_out = zeros(2, 100);
            p_out(:, 1 : cnt) = p;
            if(Flag == 0)
                app.cnt_com = cnt;
            else
                app.cnt_ori(Flag) = cnt;
            end
            
%             figure
%             imshow(I_in)
%             hold on
%             for i = 1 : cnt
%                 x = p_out(1, i);
%                 y = p_out(2, i);
%                 plot(y, x, 'r*')
%                 hold on
%             end
%             title('Harris')
        end
        
        function p_out = FAST(app, I_in, threshold, Flag)
            graph = double(I_in);
            [n, m] = size(graph);
            if (Flag ~= 0)
                n = n / (2 ^ (Flag - 1));
                m = m / (2 ^ (Flag - 1));
            end
            V = zeros(n, m);
            p_out = zeros(2, 200);
%% fast algorithm
            %      1, 5, 9, 13, 2,  3,  4, 6, 7, 8, 10, 11, 12, 14, 15 16
            dx = [-3, 0, 3, 0, -3, -2, -1, 1, 2, 3,  3,  2,  1, -1, -2, -3];
            dy = [0,  3, 0, -3, 1,  2,  3, 3, 2, 1, -1, -2, -3, -3, -2, -1];
            for i = 4 : n - 3
                for j = 4 : m - 3
                    num = 0;
                    res = 0;
                    for k = 1 : 4
                        delta = abs(graph(i + dx(k), j + dy(k)) - graph(i, j));
                        res = res + delta;
                        if delta >= threshold
                            num = num + 1;
                        end
                    end
                    if num < 3
                        V(i, j) = 0;
                        continue;
                    end
                    for k = 5 : length(dx)
                        delta = abs(graph(i + dx(k), j + dy(k)) - graph(i, j));
                        res = res + delta;
                        if delta > threshold
                            num = num + 1;
                        end
                    end
                    if num < 12
                        V(i, j) = 0;
                        continue;
                    end
                    V(i, j) = res;
                end
            end
%% non-maximum suppression
            % dxx = [-1, -1, -1, 0, 0, 1, 1, 1];
            % dyy = [-1, 0, 1, -1, 1, -1, 0, 1];
            dxx = [-2, -2, -2, -2, -2, -1, -1, -1, -1, -1, 0, 0, 0, 0, 1, 1, 1, 1, 1, 2, 2, 2, 2, 2];
            dyy = [-2, -1, 0, 1, 2, -2, -1, 0, 1, 2, -2, -1, 1, 2, -2, -1, 0, 1, 2, -2, -1, 0, 1, 2];
            cnt = 0;
            for i = 6 : n - 5
                for j = 6 : m - 5
                    flag = false;
                    for k = 1 : length(dxx)
                        if (V(i, j) <= V(i + dxx(k), j + dyy(k)))
                            flag = true;
                            break;
                        end
                    end
                    if (~flag)
                        cnt = cnt + 1;
                        p_out(1, cnt) = i;
                        p_out(2, cnt) = j;
                    end
                end
            end
            p = p_out;
            p = p(:, p(1, :) > app.S & p(1, :) < n - app.S & p(2, :) > app.S & p(2, :) < m - app.S);
            cnt = length(p(1, :));
            p_out = zeros(2, 100);
            p_out(:, 1 : cnt) = p;
            if(Flag == 0)
                app.cnt_com = cnt;
            else
                app.cnt_ori(Flag) = cnt;
            end
            
%             figure
%             imshow(I_in)
%             hold on
%             for i = 1 : cnt
%                 x = p_out(1, i);
%                 y = p_out(2, i);
%                 plot(y, x, 'r*')
%                 hold on
%             end
%             title('FAST')
        end
        
        function D_out = ORB(app, Points, I, Flag, s)
            [n, m] = size(I);
            if (Flag == 0)
                cnt = app.cnt_com;
            else
                cnt = app.cnt_ori(Flag);
                n = n / (2 ^ (Flag - 1));
                m = m / (2 ^ (Flag - 1));
            end
            D_out = zeros(cnt, app.N);
            for i = 1 : cnt
                x = Points(1, i);
                y = Points(2, i);
                M00 = calc_moment(x, y, I, 0, 0);
                M10 = calc_moment(x, y, I, 1, 0);
                M01 = calc_moment(x, y, I, 0, 1);  %计算矩，输入中心点坐标，图像，是否需要乘x或y，质心框大小
                Cx = M10 / M00;
                Cy = M01 / M00;                                %矩的质心
                theta = atan2(Cy, Cx);                         %向量旋转的角度，正值逆时针，负值顺时针
                
                p = Points;
        
                graph = I;
        
                new_s = rotate_vec(s, -theta, app.N);

                p1 = round([x; y] + [new_s(1, :); new_s(3, :)]);
                p2 = round([x; y] + [new_s(2, :); new_s(4, :)]);
        
                for k = 1 : app.N
                    if (graph(p1(1, k), p1(2, k)) < graph(p2(1, k), p2(2, k))) 
                        D_out(i, k) = 1;
                    else
                        D_out(i, k) = 0;
                    end
                end
            end
        end
        
        function Match(app, Points1, Points2, I1, I2, D1, D2, id, Flag)       %D1的长度小于等于D2，所以用D1去匹配D2
            if (Flag == 1)
                cnt_1 = app.cnt_ori(id);
                cnt_2 = app.cnt_com;
                d1 = D1(1 : cnt_1, :, id);
                d2 = D2;
            else
                cnt_1 = app.cnt_com;
                cnt_2 = app.cnt_ori(id);
                d1 = D1;
                d2 = D2(1 : cnt_1, :, id);
            end
            
            V = ones(1, cnt_2);
            res(1, 100) = struct('weight', [], 'first', [], 'second', []);
            for i = 1 : cnt_1
                res(i).weight = -1;
                res(i).first = i;
                for j = 1 : cnt_2
                    weight = sum(~xor(d1(i, :), d2(j, :)));
                    if (weight > res(i).weight && V(j) == 1)
                        res(i).second = j;
                        res(i).weight = weight;
                    end
                end
                V(res(i).second) = 0;
            end
            
            app.row_ori = app.row_ori / (2 ^ (id - 1));
            app.column_ori = app.column_ori / (2 ^ (id - 1));
%             app.row_ori = app.n;
%             app.column_ori = app.m;
            if app.imagetype_num == 1
                final = ones(max(app.row_ori, app.row_com), app.column_ori + app.column_com + 40) + 254;
            else
                final = ones(max(app.row_ori, app.row_com), app.column_ori + app.column_com + 40);
            end
            final(1 : app.row_ori, 1 : app.column_ori) = I1;
            final(1 : app.row_com, app.column_ori + 41 : app.column_ori + 40 + app.column_com) = I2; %将两幅图显示在同一张上
            figure
            if app.imagetype_num == 1
                imshow(uint8(final))
            else
                imshow(final)
            end
            hold on
%             res(1).first = 1; res(1).second = 2; res(1).weight = 256;
%             res(2).first = 2; res(2).second = 1; res(2).weight = 256;
%             res(3).first = 3; res(3).second = 6; res(3).weight = 256;
%             res(4).first = 4; res(4).second = 3; res(4).weight = 256;
%             res(5).first = 5; res(5).second = 5; res(5).weight = 256;
%             res(6).first = 6; res(6).second = 4; res(6).weight = 256;
%             res(7).first = 7; res(7).second = 7; res(7).weight = 256;
            for i = 1 : cnt_1
                pos1 = res(i).first;
                pos2 = res(i).second;
                disp([num2str(pos1) '->' num2str(pos2) '  weight=' num2str(res(i).weight)])
%                 if res(i).weight < 177
%                     continue;
%                 end
                x1 = Points1(1, pos1);
                y1 = Points1(2, pos1);
                x2 = Points2(1, pos2);
                y2 = Points2(2, pos2) + 41 + app.column_ori;
                plot(y1, x1, 'r*')
                hold on
                plot(y2, x2, 'b*')
                hold on
                plot([y1, y2], [x1, x2]);
                hold on
            end
        end
        
        function [I_out, gra] = Initialize(app, I_in)
            I = double(I_in);
            
%             figure
%             imshow(uint8(I))
            
            [n, m] = size(I);
            num = zeros(1, 256);
            for i = 1 : n
                for j = 1 : m
                    num(I(i, j) + 1) = num(I(i, j) + 1) + 1;
                end
            end
            maximum = -1;
            grey = 0;
            for i = 1 : 241
                summation = 0;
                for j = 0 : 14
                    summation = summation + num(i + j);
                end
                if summation > maximum
                    grey = i;
                    maximum = summation;
                end
            end
            if grey >= 1 && grey < 76
                gamma = 1.5;
            elseif grey >= 76 && grey < 101
                gamma = 2;
            elseif grey >= 101 && grey < 151
                gamma = 2.5;
            elseif grey >= 151 && grey <= 241
                gamma = 3.5;
            end
            alpha = 255 ^ (1 - gamma);
            I = alpha * (I .^ gamma);
            
%             figure
%             imshow(uint8(I))
%             title('enhancement')
%             MSSIM1 = check(app, I_in, I);
            
%% image sharpening
            I_out = Improved_sharpening(I);
            
%             figure
%             imshow(uint8(I_out))
%             title('sharpening')
%             MSSIM2 = check(app, I_in, I_out);
% %             
%             StrArrayNew = {['SSIM1 = ' num2str(MSSIM1) '  SSIM2 = ' num2str(MSSIM2)]};
%             logRefresh_func(app, StrArrayNew);
%             disp(['SSIM1 = ' num2str(MSSIM1) '  SSIM2 = ' num2str(MSSIM2)]);
            
%% calculate gradient for shape context
            windowy = [-1, -1, -1; 0, 0, 0; 1, 1, 1];
            windowx = [1, 0, -1; 1, 0, -1; 1, 0, -1];
            gra_x = imfilter(I_out, windowx);
            gra_y = imfilter(I_out, windowy);
            gra = atan(gra_x ./ gra_y);
%% contour extraction
            graph = double(I_out);
            threshold = graythresh(uint8(graph)) + 60 / 256;
            graph = imbinarize(graph, 256 * threshold);
            
%             figure
%             imhist(uint8(I_in));
%             figure
%             imhist(uint8(I_out));
%             
%             figure
%             imshow(graph)
%             title('binarize')
            
%             dx = [-1, -1, -1, 0, 0, 1, 1, 1];
%             dy = [-1, 0, 1, -1, 1, -1, 0, 1];
%             BW = graph;
%             for i  = 2 : n - 1
%                 for j = 2 : m - 1
%                     s = 0;
%                     for k = 1 : 8
%                         nx = i + dx(k);
%                         ny = j + dy(k);
%                         s = s + graph(nx, ny);
%                     end
%                     if s == 8
%                         BW(i, j) = 0;
%                     end
%                 end
%             end

            BW = imfill(graph, 'holes');
            x = ones(3, 3);
            BW = imdilate(BW, x, 'same');
            BW = bwperim(BW);
%             figure
%             imshow(BW)
%             title('contour')
            if app.imagetype_num == 2
                I_out = BW;
            end
        end
        
        function shape_context(app, I_ori_in, I_com_in, gra1, gra2)
            %n = 75;       %在轮廓上采样的点数
            k = 3;
            beta = 0.1;
            len = 256;
            p1 = find_points(app, I_ori_in, 0);
            p2 = find_points(app, I_com_in, 1);
            
            n = min(app.cnt1, app.cnt2);
            
            p1 = Jitendra_sampling(I_ori_in, p1, n, k, app.cnt1);
            p2 = Jitendra_sampling(I_com_in, p2, n, k, app.cnt2);
            
           
            Cs = Construct_histogram(app, p1, p2, n);
            CA = zeros(n, n);
            for i = 1 : n
                for j = 1 : n
                    CA(i, j) = 0.5 * (1 - cos(gra1(i, j) - gra2(i, j)));
                end
            end
            C_final = (1 - beta) * Cs + beta * CA;
            
%             tic
%             if app.DropDown_matchmethod == 1
%                 [C, T] = hungarian(C_final);
%             else
%                 [C, T] = KM(C_final, n);
%             end
%             toc
            
            [C, T] = hungarian(C_final);
            th = 45 * T / n;
            nn = 0;
            for i = 1 : n
                x = C(i);
                y = i;
                if C_final(x, y) > th
                    C(i) = -1;
                    nn = nn + 1;
                end
            end
            I_final = zeros(len, len);
            for i = 1 : n
                x1 = p1(1, i);
                y1 = p1(2, i);
                x_2 = p2(1, i);
                y_2 = p2(2, i);
                I_final(x1, y1) = 1;
                I_final(x_2, y_2) = 1;
            end
            figure
            imshow(I_final)
            hold on
            wrong = 0;
            tot = 0;
            for i = 1 : n
                if C(i) == -1
                    continue;
                end
                tot = tot + 1;
                x1 = p1(1, C(i));
                y1 = p1(2, C(i));
                x2 = p2(1, i);
                y2 = p2(2, i);
                if (x1 - x2) ^ 2 + (y1 - y2) ^ 2 > 8
                    wrong = wrong + 1;
                end
                plot(y1, x1, 'r*')
                hold on
                plot(y2, x2, 'bo')
                hold on
                plot([y1, y2], [x1, x2]);
                hold on
            end
%             StrArrayNew = {['accuracy = ' num2str((tot - wrong) / tot) '   ' num2str((n - nn) / n)]};
%             logRefresh_func(app, StrArrayNew);

%             ------------------------------------------------------------
            [C, T] = KM(C_final, n);
            th = 45 * T / n;
            nn = 0;
            for i = 1 : n
                x = C(i);
                y = i;
                if C_final(x, y) > th
                    C(i) = -1;
                    nn = nn + 1;
                end
            end
            I_final = zeros(len, len);
            for i = 1 : n
                x1 = p1(1, i);
                y1 = p1(2, i);
                x_2 = p2(1, i);
                y_2 = p2(2, i);
                I_final(x1, y1) = 1;
                I_final(x_2, y_2) = 1;
            end
            figure
            imshow(I_final)
            hold on
            wrong = 0;
            tot = 0;
            for i = 1 : n
                if C(i) == -1
                    continue;
                end
                tot = tot + 1;
                x1 = p1(1, C(i));
                y1 = p1(2, C(i));
                x2 = p2(1, i);
                y2 = p2(2, i);
                if (x1 - x2) ^ 2 + (y1 - y2) ^ 2 > 8
                    wrong = wrong + 1;
                end
                plot(y1, x1, 'r*')
                hold on
                plot(y2, x2, 'bo')
                hold on
                plot([y1, y2], [x1, x2]);
                hold on
            end
%             ------------------------------------------------------------
        end
        
        function p = find_points(app, I_in, Flag)
            cnt = 0;
            [n, m] = size(I_in);
            p = zeros(2, 400);
            for i = 1 : n
                for j = 1 : m
                    if I_in(i, j) == 1
                        cnt = cnt + 1;
                        p(1, cnt) = i;
                        p(2, cnt) = j;
                    end
                end
            end
            if Flag == 0
                app.cnt1 = cnt;
            else
                app.cnt2 = cnt;
            end
        end
        
        function Cs = Construct_histogram(app, p1, p2, N)
            Cs = zeros(N, N);

            mat_g = calc_dis(p1, N);
            mat_h = calc_dis(p2, N);
            for i = 1 : N
                for j = 1 : N
                    res = 0;
                    for k = 1 : 60
                        res = res + (mat_g(i, k) - mat_h(j, k)) ^ 2 / (mat_g(i, k) + mat_h(j, k) + 0.000001);
                    end
                    Cs(i, j) = res / 2;
                end
            end
        end
        
        function I_out = Construct_Pyramid(app, I_in, level)
            [n, m] = size(I_in);
            I_out = zeros(n, m, level + 1);
            I_out(:, :, 1) = I_in;
%             figure
%             subplot(2, 2, 1)
%             if app.imagetype_num == 1
%                 imshow(uint8(I_out(:, :, 1)), 'InitialMagnification', 'fit');
%             else
%                 imshow(I_out(:, :, 1), 'InitialMagnification', 'fit');
%             end
%             
%             hold on
            dx = [0, 0, 1, 1];
            dy = [0, 1, 0, 1];
            for id = 1 : level
                for i = 1 : floor(n / (2 ^ id))
                    for j = 1 : floor(m / (2 ^ id))
                        if app.pyramid_num == 1
                            for k = 1 : 4
                                I_out(i, j, id + 1) = I_out(i, j, id + 1) + ...
                                I_out(2 * i - 1 + dx(k), 2 * j - 1 + dy(k), id);
                            end
                            I_out(i, j, id + 1) = I_out(i, j, id + 1) / 4;  %下采样时取平均值
                        else
                           res = -1;
                            for k = 1 : 4
                                res = max(res, I_out(2 * i - 1 + dx(k), 2 * j - 1 + dy(k), id));
                            end
                            I_out(i, j, id + 1) = res;                      %下采样时取最大值 
                        end
                    end
                end
%                 subplot(2, 2, id + 1)
%                 if app.imagetype_num == 1
%                     imshow(uint8(I_out(1 : floor(n / (2 ^ id)), 1 : floor(m / (2 ^ id)),...
%                    id + 1)), 'InitialMagnification', 'fit');
%                 else
%                     imshow(I_out(1 : floor(n / (2 ^ id)), 1 : floor(m / (2 ^ id)),...
%                    id + 1), 'InitialMagnification', 'fit');
%                 end
%                 
%                 hold on
            end
        end
        
        function MSSIM = check(app, img, imgn)
            m = 256;
            n = 256;
            tmp = [];
            for i = 9 : 8 : m
               for j = 9 : 8 : n
                  tmp = [tmp SSIM(app, img(i - 8 : i, j - 8 : j), imgn(i - 8 : i, j - 8 : j))];        
               end
            end
        
            MSSIM = mean(tmp);
        end
        
        function re = SSIM(app, X, Y)
            X=double(X);
            Y=double(Y);
    
            ux=mean(mean(X));
            uy=mean(mean(Y));
    
            sigma2x=mean(mean((X-ux).^2));
            sigma2y=mean(mean((Y-uy).^2));   
            sigmaxy=mean(mean((X-ux).*(Y-uy)));
    
            k1=0.01;
            k2=0.03;
            L=255;
            c1=(k1*L)^2;
            c2=(k2*L)^2;
            c3=c2/2;
    
            l=(2*ux*uy+c1)/(ux*ux+uy*uy+c1);
            c=(2*sqrt(sigma2x)*sqrt(sigma2y)+c2)/(sigma2x+sigma2y+c2);
            s=(sigmaxy+c3)/(sqrt(sigma2x)*sqrt(sigma2y)+c3);
    
            re=l*c*s;
        end
        
        function calc_hist(app, I)
            [n, m] = size(I);
            h = zeros(1, 256);
            for i = 1 : n
                for j = 1 : m
                    h(round(I(i, j)) + 1) = h(round(I(i, j)) + 1) + 1;
                end
            end
            h(256) = h(256) / 2;
            h(255) = h(255) / 2;
            figure
            bar(h);
            axis([0 260 0 10000]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_browse1
        function Button_browse1Pushed(app, event)
            [filename, pathname] = uigetfile('.jpg', '*.bmp');
            if ~isequal(filename, 0)
                app.EditField_inputpath.Value = fullfile(pathname, filename);
                StrArrayNew = {'msg:输入图像文件浏览完成！'};
                logRefresh_func(app, StrArrayNew);
            else
                StrArrayNew = {'error:输入图像文件浏览失败！'};
                logRefresh_func(app, StrArrayNew);
            end
        end

        % Button pushed function: Button_browse_two
        function Button_browse_twoPushed(app, event)
            [filename, pathname] = uigetfile('.jpg', '*.bmp');
            if ~isequal(filename, 0)
                app.EditField_templatepath.Value = fullfile(pathname, filename);
                StrArrayNew = {'msg:模板图像文件浏览完成！'};
                logRefresh_func(app, StrArrayNew);
            else
                StrArrayNew = {'error:模板图像文件浏览失败！'};
                logRefresh_func(app, StrArrayNew);
            end
        end

        % Button pushed function: Button_load1
        function Button_load1Pushed(app, event)
            try
                app.I_ori = imread(app.EditField_inputpath.Value);
                imshow(app.I_ori,'Parent',app.UIAxes_input);
                StrArrayNew = {'msg:模板图像文件加载完成！'};
                logRefresh_func(app, StrArrayNew);
            catch
                StrArrayNew = {'error:模板图像文件加载失败！'};
                logRefresh_func(app, StrArrayNew);
            end
            app.imagetype_num = 0;
            app.pyramid_num = 0;
            app.matchmethod_num = 0;
            app.algorithm_num = 0;
        end

        % Button pushed function: Button_load_two
        function Button_load_twoPushed(app, event)
            try
                app.I_com = imread(app.EditField_templatepath.Value);
                imshow(app.I_com,'Parent',app.UIAxes_template);
                StrArrayNew = {'msg:模板图像文件加载完成！'};
                logRefresh_func(app, StrArrayNew);
            catch
                StrArrayNew = {'error:模板图像文件加载失败！'};
                logRefresh_func(app, StrArrayNew);
            end
        end

        % Button pushed function: Button_run
        function Button_runPushed(app, event)
            shape_feature(app, app.I_ori, app.I_com);
        end

        % Value changed function: DropDown_algorithm
        function DropDown_algorithmValueChanged(app, event)
            value = app.DropDown_algorithm.Value;
            if isequal(value, 'Harris算法')
                value = 1;
                app.DropDown_matchmethod.Visible = 'off';
                app.Label_6.Visible = 'off';
                app.DropDown_imagetype.Enable = 'on';
            elseif isequal(value, 'FAST算法')
                value = 2;
                app.DropDown_matchmethod.Visible = 'off';
                app.Label_6.Visible = 'off';
                app.DropDown_imagetype.Enable = 'on';
            elseif isequal(value, '形状上下文算法')
                value = 3;
                app.DropDown_matchmethod.Visible = 'on';
                app.Label_6.Visible = 'on';
                app.DropDown_imagetype.Value = '二值图像';
                app.DropDown_imagetype.Enable = 'off';
                app.imagetype_num = 2;
            end
            app.algorithm_num = value;
        end

        % Value changed function: DropDown_imagetype
        function DropDown_imagetypeValueChanged(app, event)
            value = app.DropDown_imagetype.Value;
            if isequal(value, '灰度图像')
                value = 1;
            elseif isequal(value, '二值图像')
                value = 2;
            end
            app.imagetype_num = value;
        end

        % Value changed function: DropDown_pyramid
        function DropDown_pyramidValueChanged(app, event)
            value = app.DropDown_pyramid.Value;
            if isequal(value, '均值')
                value = 1;
            elseif isequal(value, '最大值')
                value = 2;
            end
            app.pyramid_num = value;
        end

        % Value changed function: DropDown_matchmethod
        function DropDown_matchmethodValueChanged(app, event)
            value = app.DropDown_matchmethod.Value;
            if isequal(value, '匈牙利算法')
                value = 1;
            elseif isequal(value, 'KM算法')
                value = 2;
            end
            app.matchmethod_num = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_2.Title = '图像输入';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '输入图像路径';
            app.EditField_inputpath = uieditfield(app.Panel_2, 'text');
            app.EditField_inputpath.Value = 'C:\Users\张泽鋆\Desktop\plane1.bmp';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '模板图像路径';
            app.EditField_templatepath = uieditfield(app.Panel_2, 'text');
            app.EditField_templatepath.Value = 'C:\Users\张泽鋆\Desktop\plane1_rotated.bmp';
            app.Button_browse1 = uibutton(app.Panel_2, 'push');
            app.Button_browse1.ButtonPushedFcn = createCallbackFcn(app, @Button_browse1Pushed, true);
            app.Button_browse1.Text = '浏览';
            app.Button_load1 = uibutton(app.Panel_2, 'push');
            app.Button_load1.ButtonPushedFcn = createCallbackFcn(app, @Button_load1Pushed, true);
            app.Button_load1.Text = '加载';
            app.Button_browse_two = uibutton(app.Panel_2, 'push');
            app.Button_browse_two.ButtonPushedFcn = createCallbackFcn(app, @Button_browse_twoPushed, true);
            app.Button_browse_two.Text = '浏览';
            app.Button_load_two = uibutton(app.Panel_2, 'push');
            app.Button_load_two.ButtonPushedFcn = createCallbackFcn(app, @Button_load_twoPushed, true);
            app.Button_load_two.Text = '加载';
            app.Panel_3 = uipanel(app.Panel);
            app.Panel_3.Title = '图像显示';
            app.UIAxes_input = uiaxes(app.Panel_3);
            title(app.UIAxes_input, '输入图像')
            zlabel(app.UIAxes_input, 'Z')
            app.UIAxes_template = uiaxes(app.Panel_3);
            title(app.UIAxes_template, '模板图像')
            zlabel(app.UIAxes_template, 'Z')
            app.Panel_4 = uipanel(app.Panel);
            app.Panel_4.Title = '图像处理选项';
            app.Button_run = uibutton(app.Panel_4, 'push');
            app.Button_run.ButtonPushedFcn = createCallbackFcn(app, @Button_runPushed, true);
            app.Button_run.Text = '开始处理';
            app.Label_3 = uilabel(app.Panel_4);
            app.Label_3.Text = '算法选择';
            app.DropDown_algorithm = uidropdown(app.Panel_4);
            app.DropDown_algorithm.Items = {'', 'Harris算法', 'FAST算法', '形状上下文算法'};
            app.DropDown_algorithm.ValueChangedFcn = createCallbackFcn(app, @DropDown_algorithmValueChanged, true);
            app.DropDown_algorithm.Value = '';
            app.Label_4 = uilabel(app.Panel_4);
            app.Label_4.Text = '图像类型';
            app.DropDown_imagetype = uidropdown(app.Panel_4);
            app.DropDown_imagetype.Items = {'', '灰度图像', '二值图像'};
            app.DropDown_imagetype.ValueChangedFcn = createCallbackFcn(app, @DropDown_imagetypeValueChanged, true);
            app.DropDown_imagetype.Value = '';
            app.Label_5 = uilabel(app.Panel_4);
            app.Label_5.Text = '金字塔构建方法';
            app.DropDown_pyramid = uidropdown(app.Panel_4);
            app.DropDown_pyramid.Items = {'', '均值', '最大值'};
            app.DropDown_pyramid.ValueChangedFcn = createCallbackFcn(app, @DropDown_pyramidValueChanged, true);
            app.DropDown_pyramid.Value = '';
            app.Label_6 = uilabel(app.Panel_4);
            app.Label_6.Visible = 'off';
            app.Label_6.Text = '匹配算法';
            app.DropDown_matchmethod = uidropdown(app.Panel_4);
            app.DropDown_matchmethod.Items = {'', '匈牙利算法', 'KM算法'};
            app.DropDown_matchmethod.ValueChangedFcn = createCallbackFcn(app, @DropDown_matchmethodValueChanged, true);
            app.DropDown_matchmethod.Visible = 'off';
            app.DropDown_matchmethod.Value = '';
            app.Panel_5 = uipanel(app.Panel);
            app.Panel_5.Title = '信息输出';
            app.TextArea_log = uitextarea(app.Panel_5);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end